classdef VALKYRIE_v1 < matlab.apps.AppBase
    %VALKYRIE_V1 Data aggregation tool for porpoise detection data
    %   The app allows batch upload of C-POD and F-POD data (CP1/3 and
    %   FP1/3 files) as well as metadata input related to each deployment
    %   into the European Tracking Network data repository with further
    %   ingestion into EDITOinfra.
    %   
    %   If agreed to, the input files will be stored in the Marine Data
    %   Archive.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        FileselectionTab             matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        FilesSelectedTextArea_2      matlab.ui.control.TextArea
        SelectmetadatafileButton     matlab.ui.control.Button
        ConfirmselectionButton       matlab.ui.control.Button
        FilesSelectedTextArea        matlab.ui.control.TextArea
        SelectCFPODfilesButton       matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        MetadataTab                  matlab.ui.container.Tab
        ExportTab                    matlab.ui.container.Tab
        MessageTextArea              matlab.ui.control.TextArea
        MessageTextAreaLabel         matlab.ui.control.Label
        UploadfilestodatabaseButton  matlab.ui.control.Button
    end

    %============================
    % Properties for storing data
    %============================
    properties (Access = private)
        check            % Used for defining if CP1 or FP1 files are included
        SelectedFilePath % Path of the selected file
        conn             % Database connection
        files            % input file names
        path             % path to files to be used in CP3read_DTO   
        filtFiles        % filtered filenames
        cp1files
        cp3files
        fp1files
        fp3files
        minDate
        spec
        fieldType

        Detections
        MetadataTable     % table to hold metadata for each file selected
        CurrentFileIndex  % index to keep track of each file selected
    end

    methods (Access = private)
        

        %============================================
        % Function to update the Current File display
        %============================================
        function updateFileDisplay(app)
            % Get just the filename without extension
            [~, name, ext] = fileparts(app.filtFiles{app.CurrentFileIndex});
            app.CurrentfileTextArea.Value = [name,ext];
        end

  
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('helpers')); % allow to look for functions in 'helpers' folder

            app.CurrentFileIndex = 1; % Start with the first file
            
            app.check = 1; % if = 1: will check if both C/FP1 and C/FP3 files are included

            % Load .json cofiguration file
            configText = fileread('config/metadata_validation.json');
            app.Config = jsondecode(configText);

            % Specify the minimum expected date for detection data
            app.minDate = datetime(app.Config.MinDate, 'TimeZone', 'UTC');

            app.spec = app.Config.MetadataSpec; % mandatory field names for metadata and the data type - it is a structure where each field is the columns names and each has a 1x1 struct with the field type (and options if field type is Predefined)

        end

        % Button pushed function: SelectCFPODfilesButton
        function SelectCFPODfilesButtonPushed(app, event)
            % =====================
            % |POD file selection |
            % =====================
            % GOAL: Allow user to upload files from several deployments

            % TO DO: set a limit of files that can be uploaded at the same
            % time

            validExt = {'.CP1', '.CP3', '.FP1', '.FP3'};

            % Function where user can upload either single or multiple
            % files
            [app.files, app.path] = fileSelect(validExt);

            if isempty(app.files)
                return;
            end

            if ischar(app.files)
                app.files = {app.files}; % ensure files is in a cell array
            end
   
            app.SelectedFilePath = fullfile(app.path, app.files);% Show selected POD files in TextArea
            app.FilesSelectedTextArea.Value = app.files;

            % Check that all file pairs are complete and only POD files are
            % selected
            [isValid, fileGroups, msg] = checkFileExtension(app.SelectedFilePath, app.check); % app.check == 1 means that the function will evaluate if all pod pairs are complete

            if ~isValid
                uialert(app.UIFigure, msg, 'File Type Error');
                return;
            end
            
            % Store valid file sets and enable confirm
            app.cp1files = fileGroups.cp1;
            app.cp3files = fileGroups.cp3;
            app.fp1files = fileGroups.fp1;
            app.fp3files = fileGroups.fp3;
                
            % Filter for metadata entry (.CP3 or .FP3)
            app.filtFiles = app.files(~endsWith(app.files, '1'));  % make filtered list of filenames by removing all .CP1 and .FP1 files

            app.SelectmetadatafileButton.Enable = 'on';

        end

        % Button pushed function: SelectmetadatafileButton
        function SelectmetadatafileButtonPushed(app, event)
            % TO DO: add code for selection of metadata - ensure that file
            % is correct format


            % ===============================
            % Metadata CSV file selection
            % ===============================
            
            [file, path] = uigetfile('*.csv', 'Select Metadata File');
            
            % If user cancels the dialog
            if isequal(file, 0)
                return;
            end
           
            % Optionally display or log filename
            app.FilesSelectedTextArea_2.Value = {file};
              
            fullpath = fullfile(path, file);

            try
                tbl = loadMetadataFile(fullpath); % load metadata file - will give error if it fails
                
                checkMetadataColumns(tbl, app.RequiredMetadataColumns); % checks for recquired columns in metadata - will give error if it fails

                % Next check that datetime and coordinates are in correct format
                % - and change to correct format if not
                % TO DO: update validation scripts to match ETN metadata
                % names
                tbl = validateMetadata(tbl,app.minDate);  % Uses validateDatetime & validateCoordinates

                % Find all PODdata that has matching metadata and update
                % metadata file with match info
                tbl = matchFilenamesWithPOD(tbl, app.files);

                % Save metadata table in app
                app.Metadata = tbl;

                app.ConfirmselectionButton.Enable = 'on';
            catch ME
                uialert(app.UIFigure, ME.message, 'Metadata Error. ');
            end

            app.ConfirmselectionButton.Enable = 'on';

        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            % ===================================================
            % Confirm selection of files
            % ===================================================

            % Switch to the metadata tab
            app.TabGroup.SelectedTab = app.MetadataTab;
       
            
            app.SelectCFPODfilesButton.Enable = 'off';
            app.ConfirmselectionButton.Enable = 'off';
            app.SelectmetadatafileButton.Enable = 'off';


        end

        % Button pushed function: UploadfilestodatabaseButton
        function UploadfilesButtonPushed(app, event)
            
            % =======================
            % Extract and upload data
            % =======================

            % At the moment this will be the most labour-intensive chunk:
            %
            % 1. It extracts the POD data sequentially - using the
            % CPODread_DTO or FPODread_DTO depending on the input files.
            % 
            % 2. Extract and summarise the detection data required for the 
            % ETN database and reformat to match the database. The output
            % will be a single text file with all detections from all files
            % uploaded by a user - will have an identifier to match it to
            % metadata
            %
            % 3. Gather the metadata input from the user based on the
            % filename and generates a text file with metadata for all
            % processed files.
            %
            % 4. Push data to be ingested into the ETN database (TBD). It
            % will be send as text files: one with metadata info and one
            % with extracted detection data.

            % ===========================================================
            
            % Display message when the upload button is pressed
            app.MessageTextArea.Value = 'Importing data...';

            %================
            % Import POD data
            %================

            % For each file pair:
            % % - Import data from both XP1 and XP3 file
            % % - Run summarising/formatting function depending on file
            % type

            % Check for server connection

            % Send formatted data to database

            % Evaluate if any files are left for processing


            % % OBS: The database code will change when we know the target
            % % database!!!
            % 
            % % Establish a connection to the SQL Server
            % % Define the DSN-less connection string
            % connStr = 'Driver={SQL Server};Server=D54296\SQLEXPRESS;Database=mydatabase;Trusted_Connection=yes;';
            % 
            % % Establish the database connection using the DSN-less connection string
            % app.conn = odbc(connStr);

            % create a waitbar to show the progress
            h = waitbar(0, 'Reading and uploading files...') ;
            
            % TO DO: set up error messages to be displayed:
            % % Found error in extracting the data in the POD reader
            % % Error in the connection to the server

            % Specififies if the nall will be calculated (only if C/FP1
            % files also uploaded.
            n = '';
            if app.check == 1
                n = '-n';
            end
            
            % Create a combined table with detections - identifiable by the
            % filename:
            for i = 1:numel(app.filtFiles)
                
                % Update message for reading file
                app.MessageTextArea.Value = sprintf('Reading file %d of %d...', i, numel(app.filtFiles));
               
                % get filename and extension
                [~, filename, ext] = fileparts(app.filtFiles{i});


                % ====================================
                % Read files and summarising functions
                % ====================================
                % Get the filepath to the 
                % % extract and format files depending on their extension
                switch ext
                    case '.CP3'
                        % Import CP3 (and CP1) file and convert to ETN format
                        app.MessageTextArea.Value = sprintf('CP3 file being imported.');
                        outputminutes = CP3read_DTO(app.path,filename,n); 
                        app.MessageTextArea.Value = sprintf('Formatting CP3 file.');
                        ETN = CP3read_2_etn(outputminutes); 
                    case '.FP3'
                        % Import FP3 (and FP1) file and convert to ETN
                        % format
                        app.MessageTextArea.Value = sprintf('FP3 file being imported.');
                        outputminutes = FP3read_DTO(app.path, filename, n);
                        app.MessageTextArea.Value = sprintf('Formatting FP3 file.');
                        ETN = CP3read_2_etn(outputminutes);
                end 


                ETN = addvars(ETN, repmat({filename}, height(ETN), 1), 'NewVariableNames', 'filename', 'Before', 1); % add filename identifier
                ETN = addvars(ETN, repmat({ext}, height(ETN), 1), 'NewVariableNames', 'podType', 'Before', 1); % add column with pod type

                % TO DO: Check if match between metadata and detections
                % - and what to do if there is a mismatch!
                %if dateshift(app.Active_datetime,'start', 'day') ~= dateshift(ETN.time(1), 'start', 'day') && dateshift(app.Valid_data_until_datetime,'start', 'day') ~= dateshift(ETN.time(end), 'start', 'day')
                %    
                %end

                % Append detection data
                app.Detections = [app.Detections;ETN];

                % Update waitbar
                waitbar(i / numel(app.filtFiles), h, sprintf('Processing file %d of %d...', i, numel(app.filtFiles)))
            end

           % Close waitbar 
            close(h)     
            
            disp(length(app.Detections)) %DEBUGGING

            % if isopen(app.conn) % Check if connection to server is open
            % 
            %     % =============
            %     % Upload files
            %     % =============
            % 
            %     % Upload the metadata file and the detections data file to
            %     % the ETN database.
            %     % TO DO: how do we send it to the database as textfiles? 
            % 
            %     % TO DO: is there a way to evaluate if a deployment has
            %     % already been uploaded to the database to not have
            %     % duplicate data?
            % 
            %     % OBS: this section of code is just to shorten the name of
            %     % the upload to make it work for the SQL database - can
            %     % likely be removed for the final database
            %     fileName = app.filtFiles{i};
            %     fileName = strrep(fileName, ' ', ''); % remove spaces from file name
            %     fileName = fileName(1:min(50, length(fileName)));
            % 
            %     % Convert binary data to hexadecimal string for SQL insertion
            %     % hexFileData = dec2hex(fileData);
            %     % hexFileData = reshape(hexFileData', 1, []); % Convert to a single row
            %     fileData=getByteStreamFromArray(app.Detections); % convert to byte array
            %     hexFileData = reshape(dec2hex(fileData)', 1, []);% Convert to a single row
            % 
            %     % Create the SQL query
            %     sqlquery = sprintf('INSERT INTO Test (FileID, FileName, FileData) VALUES (%d, ''%s'', 0x%s)', fileID, fileName, hexFileData);
            % 
            %     % Execute the SQL query with error handling
            %     try
            %         exec(app.conn, sqlquery);
            % 
            %     catch ME
            %         fprintf('Error uploading', i, ME.message);
            %     end
            % 
            %     close(app.conn);
            % 
            %     uialert(app.UIFigure, 'Files uploaded successfully', 'Success');
            %     err = false;
            % else
            %     uialert(app.UIFigure, ['Connection failed: ', app.conn.Message], 'Error');
            %     err = true;
            % end
            % 
            % % If upload is successful, disable the upload and enable the select button
            % if ~err
            %     app.MessageTextArea.Value = 'Upload process completed.';
            %     app.SelectCFPODfilesButton.Enable = 'on';
            %     app.UploadfilestodatabaseButton.Enable = 'off';
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileselectionTab = uitab(app.TabGroup);
            app.FileselectionTab.Title = 'File selection';
            app.GridLayout = uigridlayout(app.FileselectionTab);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {''; 'VALKYRIE v1.0'; ''; 'Please select the raw C-POD (.CP1 and .CP3) and F-POD (.FP1 and .FP3) files to upload along with a .csv file of the corresponding metadata. Please refer to ETN for the correct format for the metadata input.'};
            app.SelectCFPODfilesButton = uibutton(app.GridLayout, 'push');
            app.SelectCFPODfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCFPODfilesButtonPushed, true);
            app.SelectCFPODfilesButton.Tooltip = {'Click to select .CP1, .CP3, .FP1 and/or .FP3 files to upload.'};
            app.SelectCFPODfilesButton.Text = 'Select C-/F-POD files';
            app.FilesSelectedTextArea = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea.Editable = 'off';
            app.ConfirmselectionButton = uibutton(app.GridLayout, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Enable = 'off';
            app.ConfirmselectionButton.Tooltip = {'Confirm selection of files.'};
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.SelectmetadatafileButton = uibutton(app.GridLayout, 'push');
            app.SelectmetadatafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmetadatafileButtonPushed, true);
            app.SelectmetadatafileButton.Enable = 'off';
            app.SelectmetadatafileButton.Tooltip = {'Click to select metadata .csv file to upload.'};
            app.SelectmetadatafileButton.Text = 'Select metadata file';
            app.FilesSelectedTextArea_2 = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea_2.Editable = 'off';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.UploadfilestodatabaseButton = uibutton(app.ExportTab, 'push');
            app.UploadfilestodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfilesButtonPushed, true);
            app.UploadfilestodatabaseButton.Enable = 'off';
            app.UploadfilestodatabaseButton.Tooltip = {'Click to upload files. This may take a few minutes.'};
            app.UploadfilestodatabaseButton.Text = 'Upload files to database';
            app.MessageTextAreaLabel = uilabel(app.ExportTab);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.ExportTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VALKYRIE_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end